classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OccupancyPanel        matlab.ui.container.Panel
        ElapsedTime           matlab.ui.control.Label
        MapBuildingLamp       matlab.ui.control.Lamp
        Gauge                 matlab.ui.control.Gauge
        Panel_3               matlab.ui.container.Panel
        AutoexploremodeLabel  matlab.ui.control.Label
        AutoexploreSwitch     matlab.ui.control.Switch
        ConnectionLamp        matlab.ui.control.Lamp
        ConnectionLampLabel   matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        BtnFullStop           matlab.ui.control.StateButton
        BtnLeft               matlab.ui.control.StateButton
        BtnBackward           matlab.ui.control.StateButton
        BtnRight              matlab.ui.control.StateButton
        BtnForward            matlab.ui.control.StateButton
        BtnCW                 matlab.ui.control.StateButton
        BtnCCW                matlab.ui.control.StateButton
        BtnConnect            matlab.ui.control.Button
        cameraAxes            matlab.ui.control.UIAxes
        mapOccupancyAxes      matlab.ui.control.UIAxes
        mapTrajectoryAxes     matlab.ui.control.UIAxes
    end

    properties(Constant)

    end
    properties (Access = private)
        statusOffColor = [0.5, 0.5, 0.5];
        statusOnColor = 'green';
        baseControl % Base control object
        connection  % Instance of RobotConnection
        laserScanner % Instance of LaserScanner class
        robotPose % Instance of RobotPoseEstimation class 
        slamHandler % Instance of the lidarSlam class
        explorer %instance of the explorer class
        lidarSlam
        worldMap
        timerToUpdateSLAMdata
        timerToUpdateMap
        timerToMoveTheRobot
        intialPosition
        
        timerRecalculatePath
        timerToUpdateSlam
        maximumRange=9
        
        currentPoseOfTheRobot
        plannedPath
        optimisedPath
        purePursuitController

        % Set goal radius
        goalRadius = 0.1;
        targetReached = false;

        minFrontDist
        
        timerToUpdateCamera 
        camHandle
        hImage 


        floorXMin = -5.000  % Minimum X boundary of the floor
        floorXMax =  5.000 % Maximum X boundary of the floor
        floorYMin = -5.000 % Minimum Y boundary of the floor
        floorYMax =  0
        
        totalEstimatedTime
        startTime

    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)
        function stopAllTimers(app)
            % Get all timer objects
            timers = timerfindall;
            
            % Stop and delete each timer
            if ~isempty(timers)
                stop(timers);
                disp('All timers have been stopped.');
            else
                disp('No active timers found.');
            end
            if ~isempty(app.slamHandler) && app.slamHandler.isUpdating
                app.slamHandler.stopUpdating();
            end
            app.AutoexploreSwitch.Value = 'Off';
            
        end
        function startAllTimers(app)
             if strcmp(app.timerToUpdateSLAMdata.Running, 'off')
                start(app.timerToUpdateSLAMdata);
             end
             if strcmp(app.timerToUpdateMap.Running, 'off')
                start(app.timerToUpdateMap);
             end
             if strcmp(app.timerRecalculatePath.Running, 'off')
                start(app.timerRecalculatePath);
             end
             if strcmp(app.timerToUpdateCamera.Running, 'off')
                start(app.timerToUpdateCamera);
             end
             if strcmp(app.timerToUpdateCamera.Running, 'off')
                start(app.timerToUpdateSlam);
             end
             if ~app.slamHandler.isUpdating
                app.slamHandler.startUpdating();
            end
        end

        function toggleControls(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnectionLamp.Color = app.statusOffColor;
                app.toggleMovementButtons("off");
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnectionLamp.Color = app.statusOnColor;
                app.toggleMovementButtons("on");
            end
        end

        function toggleBuildMapButtons(app)
            if strcmp(app.timerToUpdateMap.Running, 'off')
                app.MapBuildingLamp.Color = app.statusOffColor;
            else
                app.MapBuildingLamp.Color = app.statusOnColor;
            end
        end
 
        function toggleMovementButtons(app,state)
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW, app.BtnFullStop};
            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
            app.AutoexploreSwitch.Enable = state;
            app.AutoexploremodeLabel.Enable = state;
            if strcmp(state,"off")
                app.AutoexploreSwitch.Value = 'Off';
            end
        end

        function buttonsInitialSetup(app)
            app.toggleControls();
            
            app.MapBuildingLamp.Color = app.statusOffColor;
            
            %assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFullStop.Tag = 'STOP';

        end

        function initiateOccupancyMap(app)
            [cartesianData, currentPose ,~,~,~,~] = app.laserScanner.getScannerData();
            lidarScanObject = lidarScan(cartesianData);
            addScan(app.lidarSlam,lidarScanObject,currentPose);
            [scansSLAM,poses] = scansAndPoses(app.lidarSlam);
            app.worldMap = buildMap(scansSLAM,poses,app.lidarSlam.MapResolution,app.lidarSlam.MaxLidarRange);
            app.currentPoseOfTheRobot = currentPose;
        end

        function [x, y] = findClosestUnexplored(app)
                map = app.worldMap;
                
                % Get the occupancy grid matrix
                occMatrix = getOccupancy(map);  % Get occupancy matrix
                
                % Define the unexplored threshold (typically 0.5 represents unknown)
                unexploredValue = 0.5;
            
                % Get the robot's position in grid coordinates
                robotX = app.currentPoseOfTheRobot(1);
                robotY = app.currentPoseOfTheRobot(2);
            
                % Initialize the minimum distance and coordinates for closest unexplored area
                minDist = inf;
                x = NaN;
                y = NaN;
                
                % Get the map size
                [mapHeight, mapWidth] = size(occMatrix);
            
                % Loop through the grid cells of the occupancy matrix
                for i = 1:mapHeight
                    for j = 1:mapWidth
                        % Check if the cell is unexplored (close to 0.5)
                        occVal = occMatrix(i,j);
                        if abs(occVal - unexploredValue) < 0.01
                            % Convert grid coordinates to world coordinates
                            gridCoords = grid2world(app.worldMap, [i, j]);
                            worldX = gridCoords(1);
                            worldY = gridCoords(2);

                            % Check if the point is within the floor boundaries
                            if worldX >= app.floorXMin && worldX <= app.floorXMax && worldY >= app.floorYMin && worldY <= app.floorYMax
                                % Compute the Euclidean distance from the robot to the cell (i, j)
                                dist = sqrt((i - robotX)^2 + (j - robotY)^2);
                                
                                % Update the minimum distance and corresponding coordinates
                                if dist < minDist
                                    minDist = dist;
                                    x = round(worldX, 4);  % Extract the X coordinate
                                    y = round(worldY, 4);  % Extract the Y coordinate

                                    % Display a debug message with the coordinates and occupancy value
                                    app.targetReached = false;
                                end
                            end
                        end
                    end
                end
            end


       function updateOccupancyMap(app)
            % Plot the occupancy map on MapOccupancy UIAxes
            axes(app.mapOccupancyAxes); % Set MapOccupancy as the current axes
            cla(app.mapOccupancyAxes);  % Clear previous content
            show(app.worldMap, 'Parent', app.mapOccupancyAxes); % Plot occupancy map
            hold(app.mapOccupancyAxes, 'on');
        
            % Plot the robot's current position
            if ~isempty(app.currentPoseOfTheRobot)
                plot(app.mapOccupancyAxes, app.currentPoseOfTheRobot(1), app.currentPoseOfTheRobot(2), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b', 'DisplayName', 'Robot');
            end
        
            % Plot the planned path if available
            if ~isempty(app.plannedPath)
                plot(app.mapOccupancyAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2);
            end
        
            % Plot the planned path if available
            if ~isempty(app.optimisedPath)
                plot(app.mapOccupancyAxes, app.optimisedPath(:, 1), app.optimisedPath(:, 2), 'g:', 'LineWidth', 2);
            end
            
            % Define zoom parameters (e.g., centered around the robot position)
            if ~isempty(app.currentPoseOfTheRobot)
                zoomCenterX = app.intialPosition(1); % Center X at robot's X position
                zoomCenterY = app.intialPosition(2); % Center Y at robot's Y position
                zoomWidth = 11;   % Width of the zoomed-in area 
                zoomHeight = 11;  % Height of the zoomed-in area 
        
                % Set the x and y limits to zoom in around the robot
                xlim(app.mapOccupancyAxes, [zoomCenterX - zoomWidth/2, zoomCenterX + zoomWidth/2]);
                ylim(app.mapOccupancyAxes, [zoomCenterY - zoomHeight/2, zoomCenterY + zoomHeight/2]);
            end
        
            hold(app.mapOccupancyAxes, 'off');
            title(app.mapOccupancyAxes, 'Occupancy Grid Map Built Using Lidar SLAM');
            drawnow;
            % app.updateTrajectoryMap();
       end


        function updateTrajectoryMap(app)
            % updateTrajectoryMap Visualizes the SLAM trajectory on the MapTrajectory UIAxes.
            %
            % This method updates the trajectory visualization based on the current state
            % of the SLAM algorithm.
            
            % Set the current axes to MapTrajectory
            axes(app.mapTrajectoryAxes);
            
            % % Clear previous plots
            % cla(obj.mapTrajectoryAxes);
            
            % Display the SLAM trajectory map
            show(app.lidarSlam, 'Parent', app.mapTrajectoryAxes);

             % Hold on to overlay additional plots
            hold(app.mapTrajectoryAxes, 'on');

             % Plot the path from plannerAStarGrid
            if ~isempty(app.plannedPath)
                plot(app.mapTrajectoryAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2);
            end
            
            % Add a title to the plot
            title(app.mapTrajectoryAxes, 'SLAM Map and Trajectory');

            % Release the hold on the current axes
            hold(app.mapTrajectoryAxes, 'off');

            drawnow;
        end


        function moveTheRobot(app)

             if ~app.targetReached && ~isempty(app.plannedPath)
                % Get the robot's current pose
                robotPosition = app.currentPoseOfTheRobot;  % Assuming you have a method to get the current robot pose
        
                % Compute control inputs using the pure pursuit controller
                [v, omega] = app.purePursuitController(robotPosition);
        
                % Move the robot using the control inputs
                app.baseControl.moveRobot(v, omega,app.minFrontDist);
        
                % Recompute the distance to the goal
                distanceToGoal = norm(robotPosition(1:2) - app.purePursuitController.Waypoints(end, 1:2));
        
                % Check if the robot has reached the goal
                if distanceToGoal <= app.goalRadius
                    app.targetReached = true;
        
                    % Stop the robot
                    app.baseControl.moveRobot(0, 0,app.minFrontDist);
        
                    % Stop the timer
                    stop(app.timerToMoveTheRobot);
                end
             end
        end


        function calculateTrajectory(app)
            [x,y] = app.findClosestUnexplored();

            robotX = app.currentPoseOfTheRobot(1);
            robotY = app.currentPoseOfTheRobot(2);

            goalPosition = [robotX, robotY] - [x,y];
            disp('ai got one');
            disp(goalPosition);

            if ~isempty(goalPosition) && ~app.targetReached
               
                inflateRadious = 0.4;
                inflatedMap = copy(app.worldMap);
                                
                inflate(inflatedMap , inflateRadious);

                startPosition = [robotX, robotY];

                pathPlanner = plannerAStarGrid(inflatedMap);
                % goalPosition = [-1.9150, -1.975];

                isStartValid = checkOccupancy(inflatedMap,startPosition) < 0.5;
                isGoalValid = checkOccupancy(inflatedMap,goalPosition) < 0.5;

                goalOccupied = getOccupancy(inflatedMap,goalPosition);
                disp('goalOccupied');
                disp(goalPosition);
                disp(goalOccupied);
                disp('startPosition');
                disp(startPosition);

                if isStartValid && isGoalValid
                    try
                        firstPath = plan(pathPlanner, startPosition , goalPosition,'world');
                        if ~isempty(firstPath)

                            app.optimisedPath = optimizePath(firstPath,inflatedMap);
                            app.plannedPath = firstPath;
                            app.purePursuitController.Waypoints = firstPath; %[app.optimisedPath(1),app.optimisedPath(2)];
                            
                            if strcmp(app.timerToMoveTheRobot.Running, 'off')
                                start(app.timerToMoveTheRobot);
                            end
                        else
                            disp('No plan was found');
                            app.baseControl.moveRobot(0, 0,app.minFrontDist);
                        end
                    catch exception
                        disp(exception.message);
                        app.baseControl.moveRobot(0, 0,app.minFrontDist);
                        app.stopAllTimers();
                    end
                else
                    disp(isStartValid);
                    disp(isGoalValid);
                end
            end
        end

        function updateSLAMdata(app)
            maxrange = app.maximumRange; % meters
            disp(app.worldMap);
            [cartesianData, currentPose ,~,~,app.minFrontDist,~] = app.laserScanner.getScannerData();
            if ~isempty(cartesianData) && ~isempty(currentPose)
                lidarScanObject = lidarScan(cartesianData);
                addScan(app.lidarSlam,lidarScanObject,currentPose);
                disp(app.worldMap);
                minRange = 0.1;
                maxRange = app.maximumRange;
                validData = removeInvalidData(lidarScanObject,'RangeLimits',[minRange maxRange]);
                
                insertRay(app.worldMap,currentPose,validData,maxrange);
                app.currentPoseOfTheRobot = currentPose;
                if isempty(app.intialPosition)
                    app.intialPosition = currentPose;
                end
            end
        end

        % Update the camera feed (in a loop or timer)
        function updateCameraFeed(app)
            % Get the current camera frame from CoppeliaSim
            [res, ~, image] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_buffer);
            
            if res == app.connection.sim.simx_return_ok
                set(app.hImage, 'CData', image);  % Update the image in UIAxes
                drawnow;  % Refresh display
            end
        end

        function componentsInitialSetup(app)

            % Retrieve the handle for the robot object from CoppeliaSim
            [res, robotHandle] = app.connection.sim.simxGetObjectHandle(app.connection.clientID, '/Robot', app.connection.sim.simx_opmode_blocking);

            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [rightMotor, ~] = app.connection.getHandle('/Robot/rightMotor');
            [leftMotor, ~] = app.connection.getHandle('/Robot/leftMotor');
            
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [rightMotor, leftMotor];
            
            wheelRadius = 0.02;
            % Initialize the BaseControl object
            app.baseControl = BaseControl(app.connection,robotHandle,wheelHandles,wheelRadius);
            
        
            % Initialize SLAM algorithm with new parameters
            maxRange = app.maximumRange; % Slightly below the max Lidar range
            mapResolution = 20; % 20 cells per meter, 5cm precision
            loopClosureThreshold = 210;
            loopClosureSearchRadius = 8;
                   
            
            if res == app.connection.sim.simx_return_ok
                % Pass sim, clientID, and robotHandle to RobotPose constructor
                app.robotPose = RobotPose(app.connection.sim, app.connection.clientID, robotHandle);
                disp('Robot handle retrieved and RobotPose object initialized.');
            else
                disp('Failed to retrieve robot handle.');
                return;  % Exit if robot handle is not retrieved
            end
            
            % Initialize LaserScanner and set the initial robot position
            app.laserScanner = LaserScanner(app.connection, app.robotPose);
        
            % Initialize the SLAM handler object
            app.slamHandler = SLAMHandler(app.laserScanner, app.robotPose, mapResolution, maxRange, loopClosureThreshold, loopClosureSearchRadius, app.mapTrajectoryAxes, app.mapOccupancyAxes);
        
            % Initialize Explorer object with references to the necessary components
            % Pass sim, clientID, and robotHandle to the Explorer constructor
            app.explorer = Explorer(app.connection, app.baseControl, app.slamHandler, app.laserScanner, app.robotPose, app.connection.sim, app.connection.clientID, robotHandle);  
            
            app.initiateOccupancyMap();

            % Get the vision sensor handle
            [app.camHandle, ~] = app.connection.getHandle('/Camera');
            
            % Start image streaming
            [~, resolution, ~] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_streaming);
            
            % Initialize image display on UIAxes
            app.hImage = imshow(zeros(resolution(2), resolution(1), 3), 'Parent', app.cameraAxes);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.buttonsInitialSetup();
            app.timerToUpdateSLAMdata = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 0.3, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.updateSLAMdata());
            
            app.timerToMoveTheRobot = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 2, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.moveTheRobot());

            app.timerToUpdateMap = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 1, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.updateOccupancyMap());
            app.timerRecalculatePath = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 3, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.calculateTrajectory());

            app.timerToUpdateCamera = timer('ExecutionMode', 'fixedRate', ...
                        'Period',0.2, ...  % Adjust for desired frame rate
                        'TimerFcn', @(~,~) app.updateCameraFeed());

            maxRange = app.maximumRange; % meters
            mapResolution = 10; % cells per meter
            app.lidarSlam = lidarSLAM(mapResolution,maxRange);
            app.lidarSlam.LoopClosureThreshold = 210;
            app.lidarSlam.LoopClosureSearchRadius = 8;
            
            app.purePursuitController = controllerPurePursuit;
            app.purePursuitController.LookaheadDistance = 0.7;  % Look for the next waypoint 0.7 meters ahead
            app.purePursuitController.DesiredLinearVelocity = 0.2;  % Move at 0.7 m/s
            app.purePursuitController.MaxAngularVelocity = 0.6;  % Allow up to 0.6 rad/s angular velocity
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            if ~app.connection.isSimulationRunning
                success = app.connection.connect();
                if success
                    app.componentsInitialSetup();
                else
                    %failed to connect to Coppelia
                    uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end
            else
                app.connection.disconnect();
                app.stopAllTimers();
                
            end
            app.toggleControls();
        end

        % Value changed function: BtnBackward, BtnCCW, BtnCW, BtnForward, 
        % ...and 3 other components
        function BtnMovementValueChanged(app, event)
            value = event.Source.Value;
            
            movementType = event.Source.Tag;  % Get the movement type from the button's tag
            
            % Create a list of all movement buttons
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW};

            % Take out from the list the pressed button
            pressedButton = event.Source;
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Loop through all the other movement buttons and set their value to 0
                for i = 1:length(remainingButtons)
                    remainingButtons{i}.Value = 0;
                end

            % if the Full stop button is clicked
            if strcmp(movementType, 'STOP')
                app.stopAllTimers();
                app.baseControl.stop();
                app.BtnFullStop.Value = 0;
            else
                if strcmp(app.timerToUpdateMap.Running, 'off')
                    start(app.timerToUpdateMap);
                end
                if strcmp(app.timerToUpdateCamera.Running, 'off')
                    start(app.timerToUpdateCamera);
                end
                % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.baseControl.move(app.baseControl.WheelMovements.(movementType));
                else
                    app.baseControl.stop();
                end
            end
            app.toggleBuildMapButtons();
        end

        % Value changed function: AutoexploreSwitch
        function AutoexploreSwitchValueChanged(app, event)
            if strcmp(app.timerRecalculatePath.Running, 'off')
                app.startAllTimers();
                disp('Exploration started.');
            else
                % Stop exploration
                app.explorer.stopExploration();
                disp('Exploration stopped.');
                app.stopAllTimers();
            end
            app.toggleBuildMapButtons();
        end

        % Close request function: UIFigure
        function AppCloseRequested(app, event)
            %check if it's running and stop the simulation 
            app.stopAllTimers();
            if app.connection.isSimulationRunning
                app.connection.disconnect();
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @AppCloseRequested, true);
            app.mapTrajectoryAxes = uiaxes(app.UIFigure);
            title(app.mapTrajectoryAxes, 'Trajectory map')
            app.mapTrajectoryAxes.XTick = [];
            app.mapTrajectoryAxes.YTick = [];
            app.mapOccupancyAxes = uiaxes(app.UIFigure);
            title(app.mapOccupancyAxes, 'Occupancy map')
            app.mapOccupancyAxes.XTick = [];
            app.mapOccupancyAxes.YTick = [];
            app.cameraAxes = uiaxes(app.UIFigure);
            title(app.cameraAxes, 'Camera')
            app.cameraAxes.XTick = [];
            app.cameraAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoexploreSwitchValueChanged, true);
            app.AutoexploreSwitch.Enable = 'off';
            app.AutoexploremodeLabel = uilabel(app.Panel_3);
            app.AutoexploremodeLabel.Enable = 'off';
            app.AutoexploremodeLabel.Text = 'Auto explore mode';
            app.OccupancyPanel = uipanel(app.UIFigure);
            app.OccupancyPanel.Title = 'Occupancy %';
            app.Gauge = uigauge(app.OccupancyPanel, 'circular');
            app.MapBuildingLamp = uilamp(app.OccupancyPanel);
            app.ElapsedTime = uilabel(app.OccupancyPanel);
            app.ElapsedTime.Text = '00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end